classdef winPlayback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DockModule                     matlab.ui.container.GridLayout
        dockModule_Close               matlab.ui.control.Image
        dockModule_Undock              matlab.ui.control.Image
        Document                       matlab.ui.container.GridLayout
        RightPanel                     matlab.ui.container.GridLayout
        WaterfallPanel                 matlab.ui.container.Panel
        WaterfallPanelGrid             matlab.ui.container.GridLayout
        WaterfallCLim2                 matlab.ui.control.Spinner
        WaterfallCLim1                 matlab.ui.control.Spinner
        WaterfallCLimRefresh           matlab.ui.control.Image
        WaterfallCLimLabel             matlab.ui.control.Label
        WaterfallMeshStyle             matlab.ui.control.DropDown
        WaterfallMeshStyleLabel        matlab.ui.control.Label
        WaterfallColormap              matlab.ui.control.DropDown
        WaterfallColormapLabel         matlab.ui.control.Label
        WaterfallDecimation            matlab.ui.control.DropDown
        WaterfallDecimationValue       matlab.ui.control.Label
        WaterfallDecimationLabel       matlab.ui.control.Label
        WaterfallFunction              matlab.ui.control.DropDown
        WaterfallFunctionLabel         matlab.ui.control.Label
        WaterfallPanelLabel            matlab.ui.control.Label
        WaterfallPanelIcon             matlab.ui.control.Image
        PersistencePanel               matlab.ui.container.Panel
        PersistencePanelGrid           matlab.ui.container.GridLayout
        PersistenceCLim2               matlab.ui.control.Spinner
        PersistenceCLim1               matlab.ui.control.Spinner
        PersistenceCLimRefresh         matlab.ui.control.Image
        PersistenceCLim_Label          matlab.ui.control.Label
        PersistenceTransparency        matlab.ui.control.Spinner
        PersistenceTransparencyLabel   matlab.ui.control.Label
        PersistenceColormap            matlab.ui.control.DropDown
        PersistenceColormapLabel       matlab.ui.control.Label
        PersistenceWindowSize          matlab.ui.control.DropDown
        PersistenceWindowSizeValue     matlab.ui.control.Label
        PersistenceWindowSizeLabel     matlab.ui.control.Label
        PersistenceInterpolation       matlab.ui.control.DropDown
        PersistenceInterpolationLabel  matlab.ui.control.Label
        PersistencePanelLabel          matlab.ui.control.Label
        PersistencePanelIcon           matlab.ui.control.Image
        GeneralPanel                   matlab.ui.container.Panel
        GeneralPanelGrid               matlab.ui.container.GridLayout
        LimitsYLim2                    matlab.ui.control.Spinner
        LimitsYLim1                    matlab.ui.control.Spinner
        LimitsYLimLabel                matlab.ui.control.Label
        LimitsXLim2                    matlab.ui.control.Spinner
        LimitsXLim1                    matlab.ui.control.Spinner
        LimitsXLimLabel                matlab.ui.control.Label
        LimitsRefresh                  matlab.ui.control.Image
        LimitsPanelLabel               matlab.ui.control.Label
        LayoutRatio                    matlab.ui.control.DropDown
        LayoutRatioLabel               matlab.ui.control.Label
        GeneralPanelLabel              matlab.ui.control.Label
        GeneralPanelIcon               matlab.ui.control.Image
        LeftPanel                      matlab.ui.container.GridLayout
        FlowPanel                      matlab.ui.container.Panel
        FlowPanelGrid                  matlab.ui.container.GridLayout
        FlowChannel                    matlab.ui.control.ListBox
        FlowChannelEdit                matlab.ui.control.Image
        FlowChannelLabel               matlab.ui.control.Label
        FlowEmissions                  matlab.ui.control.Table
        FlowEmissionsAdd               matlab.ui.control.Image
        FlowEmissionsLabel             matlab.ui.control.Label
        FlowDetectionLimits            matlab.ui.control.ListBox
        FlowDetectionLimitsEdit        matlab.ui.control.Image
        FlowDetectionLabel             matlab.ui.control.Label
        FlowMetadata                   matlab.ui.control.Label
        FlowAttributesPanelRightBtn    matlab.ui.control.Image
        FlowAttributesPanelLeftBtn     matlab.ui.control.Image
        FlowAttributesPanelVisibleIdx  matlab.ui.control.Label
        FlowPanelLabel                 matlab.ui.control.Label
        SpectrumFlowList               matlab.ui.control.DropDown
        AxesAnnotation                 matlab.ui.control.Label
        AxesToolbar                    matlab.ui.container.GridLayout
        axesTool_FlowInfo              matlab.ui.control.Image
        axesTool_Separator3_2          matlab.ui.control.Image
        axesTool_DataTip               matlab.ui.control.Image
        axesTool_waterfall             matlab.ui.control.Image
        axesTool_Separator3            matlab.ui.control.Image
        axesTool_occupancy             matlab.ui.control.Image
        axesTool_Separator2            matlab.ui.control.Image
        axesTool_persistence           matlab.ui.control.Image
        axesTool_maxHold               matlab.ui.control.Image
        axesTool_average               matlab.ui.control.Image
        axesTool_minHold               matlab.ui.control.Image
        axesTool_crearWrite            matlab.ui.control.Image
        axesTool_Separator1            matlab.ui.control.Image
        axesTool_Pan                   matlab.ui.control.Image
        axesTool_RestoreView           matlab.ui.control.Image
        AxesContainer                  matlab.ui.container.Panel
        Toolbar                        matlab.ui.container.GridLayout
        tool_OpenPopupProject          matlab.ui.control.Image
        tool_LayoutRight               matlab.ui.control.Image
        tool_UploadFinalFile           matlab.ui.control.Image
        tool_GenerateReport            matlab.ui.control.Image
        tool_TimestampLabel            matlab.ui.control.Label
        tool_TimestampSlider           matlab.ui.control.Slider
        tool_LoopControl               matlab.ui.control.Image
        tool_Play                      matlab.ui.control.Image
        tool_LayoutLeft                matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'PLAYBACK'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
        popupContainer

        SubTabGroup = struct('Children', -1, 'UserData', [])

        % Handles dos eixos cartesianos utilizados por este módulo. No futuro,
        % simplificar para uma lista de handles, permitindo a criação dinâmica
        % de quantos eixos forem necessários, como ocorre na geração do relatório.
        UIAxes1
        UIAxes2
        UIAxes3

        % Informações relacionadas ao specData selecionado, com atalhos para 
        % os principais metadados e a definição dos limites dos eixos x, y e 
        % z dos eixos cartesianos. No futuro, remover essa propriedade.
        bandObj

        % Armazena limites padrão dos eixos cartesianos computados em método 
        % de class.Band (app.bandObj).
        restoreView = struct( ...
            'ID', {}, ...
            'xLim', {}, ...
            'yLim', {}, ...
            'cLim', {} ...
        )

        % Controla o estado de atualização do plot:
        %  -1: mudança de fluxo espectral
        %   0: finaliza a apresentação da última varredura
        %   1: atualiza alguma característica do plot em andamento
        plotUpdateEvent = 0

        % Handles dos objetos gráficos do plot.
        plotHandles

        % Índice da varredura atual.
        sweepTimeIdx
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        projectData
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, varargin)
            try
                switch class(callingApp)
                    case {'winAppAnalise', 'winAppAnalise_exported'}
                        eventName = varargin{1};

                        switch eventName
                            case {'onFileListAdded', 'onFileListRemoved', 'onFileFilterChanged'}
                                updateFlowDropDown(app)

                                if app.plotUpdateEvent
                                    app.plotUpdateEvent = -1;
                                else
                                    onFlowDropDownValueChanged(app)
                                end

                            case 'onTabNavigatorButtonPushed'
                                if app.plotUpdateEvent
                                    app.plotUpdateEvent = 0;
                                end

                            otherwise
                                error('auxApp:winPlayback:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end

                    otherwise
                        error('auxApp:winPlayback:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            appName = class.Constants.appName;
            switch tabIndex
                case 1 % PLAYBACK
                    elToModify = {
                        app.AxesToolbar;
                        app.SpectrumFlowList;
                        app.FlowPanelLabel;
                        app.FlowMetadata;
                        app.FlowDetectionLimitsEdit;
                        app.FlowEmissionsAdd;
                        app.FlowChannelEdit;
                        app.tool_LayoutLeft;
                        app.tool_Play;
                        app.tool_LoopControl;
                        app.tool_OpenPopupProject;
                        app.tool_GenerateReport;
                        app.tool_UploadFinalFile;
                        app.tool_LayoutRight;
                        app.dockModule_Undock;
                        app.dockModule_Close
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.AxesToolbar.UserData.id,             'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                            struct('appName', appName, 'dataTag', app.SpectrumFlowList.UserData.id,        'selector', 'input', 'styleImportant', struct('height', '44px'), 'dropDownBackgroundColor', 'rgba(183, 49, 44, 0.75)'), ...
                            struct('appName', appName, 'dataTag', app.FlowPanelLabel.UserData.id,          'styleImportant', struct('borderLeft', '3px solid #b7312c', 'paddingLeft', '8px')), ...
                            struct('appName', appName, 'dataTag', app.tool_LayoutLeft.UserData.id,         'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna visibilidade do painel à esquerda')), ...
                            struct('appName', appName, 'dataTag', app.tool_Play.UserData.id,               'tooltip', struct('defaultPosition', 'top',    'textContent', 'Controla execução do playback da monitoração')), ...
                            struct('appName', appName, 'dataTag', app.tool_LoopControl.UserData.id,        'tooltip', struct('defaultPosition', 'top',    'textContent', 'Controla loop da execução do playback')), ...
                            struct('appName', appName, 'dataTag', app.tool_OpenPopupProject.UserData.id,   'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita informações do projeto<br>(fiscalizada, arquivo de backup etc)')), ...
                            struct('appName', appName, 'dataTag', app.tool_GenerateReport.UserData.id,     'tooltip', struct('defaultPosition', 'top',    'textContent', 'Gera relatório')), ...
                            struct('appName', appName, 'dataTag', app.tool_UploadFinalFile.UserData.id,    'tooltip', struct('defaultPosition', 'top',    'textContent', 'Upload relatório')), ...
                            struct('appName', appName, 'dataTag', app.tool_LayoutRight.UserData.id,        'tooltip', struct('defaultPosition', 'top',    'textContent', 'Alterna visibilidade do painel à direita')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Undock.UserData.id,       'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Close.UserData.id,        'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')), ...
                            struct('appName', appName, 'dataTag', app.FlowDetectionLimitsEdit.UserData.id, 'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita os limites de detecção')), ...
                            struct('appName', appName, 'dataTag', app.FlowEmissionsAdd.UserData.id,        'tooltip', struct('defaultPosition', 'top',    'textContent', 'Busca novas emissões')), ...
                            struct('appName', appName, 'dataTag', app.FlowChannelEdit.UserData.id,         'tooltip', struct('defaultPosition', 'top',    'textContent', 'Edita os canais')) ...
                        });
                    catch
                    end

                    try
                        ui.TextView.startup(app.jsBackDoor, app.FlowMetadata,  appName, struct('class', {{'textview--borderless'}}));
                    catch
                    end

                otherwise
                     % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            app.projectData = app.mainApp.projectData;
            app.bandObj = model.Band('appAnalise:PLAYBACK', app.mainApp);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            app.FlowAttributesPanelVisibleIdx.UserData.index = 1;
            app.axesTool_Pan.UserData.status = false;
            app.axesTool_DataTip.UserData.status = false;
            app.axesTool_minHold.UserData = struct('status', false, 'imageSource', {{'MinHold_32Filled.png', 'MinHold_32.png'}});
            app.axesTool_average.UserData = struct('status', false, 'imageSource', {{'Average_32Filled.png', 'Average_32.png'}});
            app.axesTool_maxHold.UserData = struct('status', false, 'imageSource', {{'MaxHold_32Filled.png', 'MaxHold_32.png'}});
            app.axesTool_persistence.UserData.status = false;
            app.axesTool_occupancy.UserData.status = false;
            app.axesTool_waterfall.UserData.status = false;
            app.tool_LoopControl.UserData.loopMode = true;

            initializeAxes(app)

            addStyle(app.FlowEmissions, uistyle('HorizontalAlignment', 'center'), "column", 1)
            addStyle(app.FlowEmissions, uistyle('HorizontalAlignment', 'right'), "column", 2:3)
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            updateFlowDropDown(app)
            onFlowDropDownValueChanged(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initializeAxes(app)
            hParent = tiledlayout(app.AxesContainer, 4, 1, "Padding", "compact", "TileSpacing", "compact");

            app.UIAxes1 = plot.axes.Creation(hParent, 'Cartesian', {'TitleHorizontalAlignment', 'right', 'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [2 1];
            
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'YScale', app.mainApp.General.plot.cartesianAxes.yOccupancyScale});
            app.UIAxes2.Layout.Tile = 3;
            
            app.UIAxes3 = plot.axes.Creation(hParent, 'Cartesian', {'Layer', 'top', 'Box', 'on', 'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes3.Layout.Tile = 4;            

            plot.axes.Colormap(app.UIAxes1, app.PersistenceColormap.Value)
            plot.axes.Colormap(app.UIAxes3, app.WaterfallColormap.Value)
            plot.axes.Colorbar(app.UIAxes3, app.mainApp.General.plot.waterfall.Colorbar)

            ylabel(app.UIAxes1, 'Nível (dB)')
            set(app.UIAxes1.Title, Visible='on', FontSize=10, FontWeight='normal', HorizontalAlignment='right', Color=[.8,.8,.8])
            ylabel(app.UIAxes2, 'Ocupação (%)')
            xlabel(app.UIAxes3, 'Frequência (MHz)')
            ylabel(app.UIAxes3, 'Instante')

            plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
            plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)

            linkaxes([app.UIAxes1, app.UIAxes2, app.UIAxes3], 'x')
            addlistener(app.UIAxes1, 'XLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes1, 'YLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes1, 'CLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes3, 'CLim', 'PostSet', @syncUIAxesLimitsWithSpinners);

            plot.axes.Interactivity.DefaultCreation([app.UIAxes1, app.UIAxes2], [dataTipInteraction, regionZoomInteraction, rulerPanInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes3,                [dataTipInteraction, regionZoomInteraction])

            function syncUIAxesLimitsWithSpinners(src, evt)
                switch evt.AffectedObject
                    case app.UIAxes1
                        switch src.Name
                            case 'XLim'
                                evtName  = app.LimitsXLim1.Tag;
                                evtValue = round(evt.AffectedObject.XLim, 3);

                                if app.LimitsXLim1.Value == evtValue(1) && app.LimitsXLim2.Value == evtValue(2)
                                    return
                                end

                                app.LimitsXLim1.Value = evtValue(1);
                                app.LimitsXLim2.Value = evtValue(2);

                            case 'YLim'
                                evtName  = app.LimitsYLim1.Tag;
                                evtValue = round(evt.AffectedObject.YLim, 1);

                                if app.LimitsYLim1.Value == evtValue(1) && app.LimitsYLim2.Value == evtValue(2)
                                    return
                                end

                                app.LimitsYLim1.Value = evtValue(1);
                                app.LimitsYLim2.Value = evtValue(2);

                            case 'CLim'
                                evtName  = app.PersistenceCLim1.Tag;
                                evtValue = round(evt.AffectedObject.CLim, 3);

                                if app.PersistenceCLim1.Value == evtValue(1) && app.PersistenceCLim2.Value == evtValue(2)
                                    return
                                end

                                app.PersistenceCLim1.Value = evtValue(1);
                                app.PersistenceCLim2.Value = evtValue(2);

                            otherwise
                                return
                        end

                    case app.UIAxes3
                        switch src.Name
                            case 'CLim'
                                evtName  = app.WaterfallCLim1.Tag;
                                evtValue = round(evt.AffectedObject.CLim);

                                if app.WaterfallCLim1.Value == evtValue(1) && app.WaterfallCLim2.Value == evtValue(2)
                                    return
                                end

                                app.WaterfallCLim1.Value = evtValue(1);
                                app.WaterfallCLim2.Value = evtValue(2);

                            otherwise
                                return
                        end

                    otherwise
                        return
                end

                specData = app.bandObj.SpecData;
                if ~isempty(specData)
                    update(specData, 'UserData:PlotDisplayConfig', evtName, evtValue)
                end

                checkAxesLimitsCustomizations(app)
            end
        end

        %-----------------------------------------------------------------%
        % ## LAYOUT ##
        %-----------------------------------------------------------------%
        function updateFlowDropDown(app)
            specData = app.mainApp.specData;

            % Aberto, em 10/03/2026, reporte de BUG relacionado ao uidropdown, 
            % quando aplicado estilo "html". Ao apagar lista, o MATLAB não
            % apaga o valor atual do elemento na GUI. Assim que resolver
            % isso, basta inserir o addStyle uma única vez, não precisando
            % removê-lo.

            if ~isempty(specData)
                if isempty(app.SpectrumFlowList.StyleConfigurations)
                    addStyle(app.SpectrumFlowList, uistyle('Interpreter', 'html'))
                end

                previousValue = app.SpectrumFlowList.Value;
            
                [receivers, ~, indexes] = unique({specData.Receiver});
                items = {};
                itemsData = [];
                
                for ii = 1:numel(receivers)
                    receiverIdxs = find(indexes == ii)';
                    receiverName = util.layoutTreeNodeText(receivers{ii}, 'play_TreeBuilding');
                    
                    for jj = receiverIdxs
                        freqStart = specData(jj).MetaData.FreqStart / 1e+6;
                        freqStop  = specData(jj).MetaData.FreqStop  / 1e+6;
                        
                        reportStatus = '';
                        if ~isempty(specData(jj).UserData) && specData(jj).UserData.ReportInclude
                            reportStatus = '&emsp;&#x1F7E2;';
                        end
            
                        items{end+1} = sprintf('%s<br>└── %.3f&ensp;a&ensp;%.3f MHz%s', receiverName, freqStart, freqStop, reportStatus);
                        itemsData(end+1) = jj;
                    end
                end
            
                currentValue = {};    
                if ~isempty(previousValue)
                    if isnumeric(previousValue) && ismember(previousValue, itemsData)
                        currentValue = {'Value', previousValue};
                    end
                end
            
                set(app.SpectrumFlowList, 'Items', items, 'ItemsData', itemsData, currentValue{:})

            else             
                removeStyle(app.SpectrumFlowList)
                app.SpectrumFlowList.Items = {};
            end
        end

        %-----------------------------------------------------------------%
        function updateFlowView(app, idx)
            % Caso não esteja em cache os dados do fluxo espectral selecionado, 
            % procede-se a sua leitura, preenchendo a propriedade "Data" com
            % timestamps, níveis das varreduras, azimutes e notas de qualidade.
            % Caso ocorra erro na leitura, o registro é excluído e o layout é
            % reinicializado.
            specData = [];
            if ~isempty(idx)
                specData = app.mainApp.specData(idx);
            end

            if ~isempty(specData) && (isempty(specData.Data) || (numel(specData.Data{1}) ~= sum(specData.RelatedFiles.NumSweeps)))
                requestVisibilityChange(app.progressDialog, 'visible', 'unlocked')

                try
                    populateSpectrum(specData, app.mainApp.metaData, app.mainApp.channelObj, app.mainApp.General)
                catch ME
                    ui.Dialog(app.UIFigure, 'error', ME.message);

                    delete(app.mainApp.specData(idx))
                    app.mainApp.specData(idx) = [];
                    updateFlowDropDown(app)
                    return
                end

                requestVisibilityChange(app.progressDialog, 'hidden', 'unlocked')
            end

            % Atualiza app.bandObj, instância de model.Band, que guarda
            % informações derivadas da instância de model.SpecData sob análise.
            % A partir desse momento, a referência ao specData selecionado
            % se dará por app.bandObj.SpecData.
            updateSpectrumInfo(app.bandObj, specData);

            % Atualiza PAINEL À ESQUERDA com metadados do fluxo, algoritmos de
            % ocupação, detecção e classificação, além da lista de emissões.
            updateUIControlsState(app, specData)
            updateUIPanelContent(app, specData)

            % Reseta o PLOT e atualiza informações que suportam o plot, como 
            % as dispostas no painel à direita.            
            resetPlotState(app, specData)

            % Atualiza PAINEL À DIREITA.
            applyCustomPlaybackSettings(app, specData)
        end

        %-----------------------------------------------------------------%
        function idx = findSpecDataIndex(app)
            idx = app.SpectrumFlowList.Value;
        end

        %-----------------------------------------------------------------%
        function updateUIControlsState(app, specData)
            % Atualiza atributos "Enable" e "Visible" dos componentes
            % afetados pelo estado atual de specData.
            nonEmptySpecData = ~isempty(specData);            
            hasMoreThanTwoSamples = nonEmptySpecData && sum(specData.RelatedFiles.NumSweeps) > 2;
            isWaterfallRenderedAsImage = hasMoreThanTwoSamples && strcmp(specData.UserData.PlotDisplayConfig.waterfall.function, 'image');
            isOccupancyFlow = nonEmptySpecData && ismember(specData.MetaData.DataType, class.Constants.occDataTypes);

            set([
                app.FlowDetectionLimitsEdit;
                app.FlowEmissionsAdd;
                app.FlowChannelEdit;
                app.axesTool_crearWrite;
                app.axesTool_minHold;
                app.axesTool_average;
                app.axesTool_maxHold;
                app.axesTool_persistence;
                app.axesTool_occupancy;
                app.axesTool_waterfall;
                app.axesTool_DataTip;
                app.axesTool_FlowInfo;
                app.tool_Play;
                app.tool_LoopControl;
                app.tool_TimestampSlider;
                app.tool_GenerateReport
            ], 'Enable', nonEmptySpecData)

            app.axesTool_persistence.Enable = hasMoreThanTwoSamples;
            app.axesTool_waterfall.Enable   = hasMoreThanTwoSamples;
            app.axesTool_occupancy.Enable   = hasMoreThanTwoSamples && ~isOccupancyFlow;            
            app.tool_UploadFinalFile.Enable = ~isempty(app.projectData.modules.(app.Context).generatedFiles.lastHTMLDocFullPath);

            % DataCursorMode
            % O DataCursorMode é, de forma geral, uma interação ruim p/ eixos
            % cartesianos por bloquear as outras (Pan, RegionZoom etc). Por
            % essa razão, restringir o DataCursorMode apenas ao eixo específico
            % em que está sendo plotado a imagem (que não suporta a interação 
            % padrão de DataTip).
            app.axesTool_DataTip.Enable = isWaterfallRenderedAsImage;

            if ~isWaterfallRenderedAsImage && app.axesTool_DataTip.UserData.status
                onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_DataTip))
            end
        end

        %-----------------------------------------------------------------%
        function updateUIPanelContent(app, specData)
            if ~isempty(specData)
                app.FlowMetadata.Text = util.HtmlTextGenerator.ThreadMetaData(specData);

                if specData.UserData.DetectionSubBandsEnabled && ~isempty(specData.UserData.DetectionSubBands)
                    app.FlowDetectionLimits.Items = cellstr(string(specData.UserData.DetectionSubBands.FreqStart) + " – " + string(specData.UserData.DetectionSubBands.FreqStop) + " MHz");
                else
                    app.FlowDetectionLimits.Items = {sprintf('%.3f – %.3f MHz (padrão)', specData.MetaData.FreqStart/1e+6, specData.MetaData.FreqStop/1e+6)};
                end

                emissionTable = specData.UserData.Emissions(:, {'Frequency', 'BandWidthkHz', 'Description'});
                if ~isempty(emissionTable)
                    emissionTable.('#') = string(1:height(emissionTable))';
                    emissionTable.Frequency = arrayfun(@(x) sprintf('%.3f', x), emissionTable.Frequency, "UniformOutput", false);
                    emissionTable.BandWidthkHz = arrayfun(@(x) sprintf('%.3f', x), emissionTable.BandWidthkHz, "UniformOutput", false);

                    app.FlowEmissions.Data  = emissionTable(:, {'#', 'Frequency', 'BandWidthkHz', 'Description'});
                else
                    app.FlowEmissions.Data = [];
                end
                
                channelLibIndex = specData.UserData.ChannelLibraryRelatedIndexes;
                if isempty(channelLibIndex)
                    app.FlowChannel.Items = {};
                else
                    app.FlowChannel.Items = arrayfun(@(x) sprintf('%.3f – %.3f MHz (%s)', x.Band(1), x.Band(2), x.Name), app.mainApp.channelObj.Channel(channelLibIndex), 'UniformOutput', false);
                end

            else
                app.FlowMetadata.Text = '';
                app.FlowDetectionLimits.Items = {};
                app.FlowEmissions.Data = [];
                app.FlowChannel.Items = {};
            end
        end

        %-----------------------------------------------------------------%
        function applyCustomPlaybackSettings(app, specData)
            if ~isempty(specData)
                iconDictionary = dictionary([true, false], [1, 2]);
    
                % CONTROLES AXESTOOLBAR
                if ~isequal(app.axesTool_minHold.UserData, specData.UserData.PlotDisplayConfig.controls.minHold)
                    app.axesTool_minHold.UserData.status  = specData.UserData.PlotDisplayConfig.controls.minHold;
                    app.axesTool_minHold.ImageSource      = app.axesTool_minHold.UserData.imageSource{iconDictionary(app.axesTool_minHold.UserData.status)};
                end
    
                if ~isequal(app.axesTool_average.UserData, specData.UserData.PlotDisplayConfig.controls.average)
                    app.axesTool_average.UserData.status  = specData.UserData.PlotDisplayConfig.controls.average;
                    app.axesTool_average.ImageSource      = app.axesTool_average.UserData.imageSource{iconDictionary(app.axesTool_average.UserData.status)};
                end
    
                if ~isequal(app.axesTool_maxHold.UserData, specData.UserData.PlotDisplayConfig.controls.maxHold)
                    app.axesTool_maxHold.UserData.status  = specData.UserData.PlotDisplayConfig.controls.maxHold;
                    app.axesTool_maxHold.ImageSource      = app.axesTool_maxHold.UserData.imageSource{iconDictionary(app.axesTool_maxHold.UserData.status)};
                end
    
                app.axesTool_persistence.UserData.status  = specData.UserData.PlotDisplayConfig.controls.persistence;
                app.axesTool_occupancy.UserData.status    = specData.UserData.PlotDisplayConfig.controls.occupancy;
                app.axesTool_waterfall.UserData.status    = specData.UserData.PlotDisplayConfig.controls.waterfall;
    
                % CONTROLES PAINEL À DIREITA
                app.LayoutRatio.Items                     = { specData.UserData.PlotDisplayConfig.layoutRatio };
                
                plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                plot.axes.Layout.RatioAspect([ app.UIAxes1, app.UIAxes2, app.UIAxes3 ], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)
                
                app.PersistenceInterpolation.Value        = specData.UserData.PlotDisplayConfig.persistence.interpolation;
                app.PersistenceWindowSize.Value           = specData.UserData.PlotDisplayConfig.persistence.windowSize;
                app.PersistenceWindowSizeValue.Text       = specData.UserData.PlotDisplayConfig.persistence.windowSize;
                app.PersistenceTransparency.Value         = specData.UserData.PlotDisplayConfig.persistence.transparency;
                app.PersistenceColormap.Value             = specData.UserData.PlotDisplayConfig.persistence.colormap;
    
                app.WaterfallFunction.Value               = specData.UserData.PlotDisplayConfig.waterfall.function;
                app.WaterfallDecimation.Value             = specData.UserData.PlotDisplayConfig.waterfall.decimation;
                app.WaterfallDecimationValue.Text         = specData.UserData.PlotDisplayConfig.waterfall.decimation;
                app.WaterfallMeshStyle.Value              = specData.UserData.PlotDisplayConfig.waterfall.meshStyle;            
                app.WaterfallColormap.Value               = specData.UserData.PlotDisplayConfig.waterfall.colormap;
    
                app.WaterfallCLim1.Value                  = specData.UserData.PlotDisplayConfig.limits.waterfall.current(1);
                app.WaterfallCLim2.Value                  = specData.UserData.PlotDisplayConfig.limits.waterfall.current(2);
            end

            updatePersistencePanel(app, 'initialization')
            updateWaterfallPanel(app)
            checkAxesLimitsCustomizations(app)
        end

        %-----------------------------------------------------------------%
        function checkAxesLimitsCustomizations(app)
            specData = app.bandObj.SpecData;
            
            if ~isempty(specData)
                limits = specData.UserData.PlotDisplayConfig.limits;                
                app.LimitsRefresh.Visible = ~isequal(limits.frequency.initial, limits.frequency.current) || ~isequal(limits.level.initial, limits.level.current);
                app.PersistenceCLimRefresh.Visible = ~strcmp(app.UIAxes1.UserData.CLimMode, 'auto') && strcmp(app.PersistenceWindowSize.Value, 'full');
                app.WaterfallCLimRefresh.Visible = ~strcmp(app.UIAxes3.UserData.CLimMode, 'auto');
                
            else
                set([
                    app.LimitsRefresh;
                    app.PersistenceCLimRefresh;
                    app.WaterfallCLimRefresh
                ], 'Visible', false)
            end
        end

        %-----------------------------------------------------------------%
        % ## PLOT CONTROLLER ##
        %-----------------------------------------------------------------%
        function resetPlotState(app, specData)
            cla([app.UIAxes1, app.UIAxes2, app.UIAxes3])
            
            ysecondarylabel(app.UIAxes2, '')
            ysecondarylabel(app.UIAxes3, '')

            app.UIAxes1.UserData.CLimMode = 'auto';
            app.UIAxes3.UserData.CLimMode = 'auto';

            app.plotHandles = struct( ...
                'clearWrite', [], ...
                'minHold', [], ...
                'average', [], ...
                'maxHold', [], ...
                'persistence', [], ...
                'selectedEmission', [], ...
                'emissionMarkers', [], ...
                'thresholdLine', [], ...
                'thresholdLabel', [], ...
                'waterfall', [], ...
                'waterfallTime', [] ...
            );
    
            app.sweepTimeIdx = 1;
            app.tool_TimestampSlider.Value = 0;
            resetRestoreView(app)

            if ~isempty(specData)
                app.tool_TimestampLabel.Text = sprintf('1 de %d\n%s', app.bandObj.NumSweeps, app.bandObj.YLimitsTime(1));
                app.AxesAnnotation.Text = sprintf('%s \n%.3f – %.3f MHz ', app.bandObj.Receiver, app.bandObj.FreqStart, app.bandObj.FreqStop);

            else
                app.tool_TimestampLabel.Text = '';
                app.AxesAnnotation.Text = '';

                if app.axesTool_occupancy.UserData.status || app.axesTool_waterfall.UserData.status
                    app.axesTool_occupancy.UserData.status = false;
                    app.axesTool_waterfall.UserData.status = false;

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)
                end

                onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_RestoreView))
            end
        end

        %-----------------------------------------------------------------%
        function resetRestoreView(app)
            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', app.bandObj.XLimits, 'yLim', app.bandObj.YLimitsLevel, 'cLim', 'auto');
            app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', app.bandObj.XLimits, 'yLim', [0, 100],                 'cLim', 'auto');
            app.restoreView(3) = struct('ID', 'app.UIAxes3', 'xLim', app.bandObj.XLimits, 'yLim', app.bandObj.YLimitsTime,  'cLim', app.bandObj.CLimits);
        end

        %-----------------------------------------------------------------%
        function updatePlot(app)
            specData = app.bandObj.SpecData;
            if isempty(specData)
                return
            end
        
            if isempty(app.plotHandles.clearWrite)
                set(app.UIAxes1, 'XLim', app.restoreView(1).xLim, 'YLim', app.restoreView(1).yLim)
                ylabel(app.UIAxes1, sprintf('Nível (%s)', app.bandObj.LevelUnit))
        
                % (a) ClearWrite, MinHold, Average, e MaxHold
                for plotTag = ["clearWrite", "minHold", "average", "maxHold"]
                    if ismember(plotTag, {'minHold', 'average', 'maxHold'}) && ~eval(sprintf('app.axesTool_%s.UserData.status', plotTag))
                        continue
                    end
        
                    app.plotHandles.(plotTag) = plot.draw2D.OrdinaryLine(app.UIAxes1, plotTag, app.bandObj, app.sweepTimeIdx);
                    plot.datatip.Template(app.plotHandles.(plotTag), "Frequency+Level", app.bandObj.LevelUnit)
                end
                
                % (b) Persistence
                if app.axesTool_persistence.UserData.status
                    updatePersistencePlot(app, 'Creation')
                end

                % Emissões
                % plot.draw2D.ClearWrite_old(app, idx, 'InitialPlot', 1)

                % BandLimits & Channels
                % plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'bandLimits')
                % plot_Draw_Channels(app, idx)
        
                % Occupancy
                if app.axesTool_occupancy.UserData.status
                    updateOccupancyPlot(app, 'Creation', specData)
                end
        
                % Waterfall
                if app.axesTool_waterfall.UserData.status
                    updateWaterfallPlot(app)
                end
        
                % customPlayback >> DataTips
                if ~isempty(specData.UserData.PlotDisplayConfig.dataTips)
                    dtConfig = specData.UserData.PlotDisplayConfig.dataTips;
                    dtParent = [app.UIAxes1, app.UIAxes2, app.UIAxes3];
                    plot.datatip.Create('customPlayback', dtConfig, dtParent)
                end
        
            else
                % ClearWrite, MinHold, Average, e MaxHold
                for plotTag = ["clearWrite", "minHold", "average", "maxHold"]
                    if ismember(plotTag, {'minHold', 'average', 'maxHold'})
                        if ~eval(sprintf('app.axesTool_%s.UserData.status', plotTag)) || isinf(app.mainApp.General.context.PLAYBACK.integration.traceMode)
                            continue
                        end
                    end
        
                    plot.draw2D.OrdinaryLineUpdate(plotTag, app.plotHandles.(plotTag), app.bandObj, app.sweepTimeIdx);
                end
        
                for ii = 1:numel(app.plotHandles.emissionMarkers)
                    app.plotHandles.emissionMarkers(ii).Position(2) = app.plotHandles.clearWrite.YData(app.plotHandles.clearWrite.MarkerIndices(ii));
                end
        
                % Persistence
                updatePersistencePlot(app, 'Update')

                % WaterfallTime
                if app.axesTool_waterfall.UserData.status && ~isempty(app.plotHandles.waterfallTime)
                    plot.draw2D.OrdinaryLineUpdate('waterfallTime', app.plotHandles.waterfallTime, app.bandObj, app.sweepTimeIdx);
                end
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        % ## PERSISTENCE ##
        %-----------------------------------------------------------------%
        function updatePersistencePanel(app, operationType)
            elementHandles = findobj(app.PersistencePanelGrid.Children, '-not', 'Type', 'uilabel', '-or', 'Type', 'uiimage');
            set(elementHandles, 'Enable', app.axesTool_persistence.UserData.status)

            switch operationType
                case 'initialization'
                    % ...

                case 'updateLimits'
                    spinnersHandles = [ app.PersistenceCLim1, app.PersistenceCLim2 ];
        
                    if ~isempty(app.plotHandles.persistence)
                        switch app.PersistenceWindowSize.Value
                            case 'full'
                                set(spinnersHandles, 'Enable', 1)
                            otherwise
                                set(spinnersHandles, 'Enable', 0)
                        end
        
                        app.restoreView(1).cLim = app.UIAxes1.CLim;
                        app.PersistenceCLim1.Value = round(app.UIAxes1.CLim(1), 3);
                        app.PersistenceCLim2.Value = round(app.UIAxes1.CLim(2), 3);
        
                    else
                        set(spinnersHandles, 'Enable', 0)
                    end
            end
        end

        %-----------------------------------------------------------------%
        function updatePersistencePlot(app, operationType)
            switch operationType
                case 'Creation'
                    [app.plotHandles.persistence, app.PersistenceWindowSizeValue.Text] = plot.Persistence('Creation', app.plotHandles.persistence, app.UIAxes1, app.bandObj, app.sweepTimeIdx);
                    updatePersistencePanel(app, 'updateLimits')

                case 'Update'
                    if app.axesTool_persistence.UserData.status && ~strcmp(app.PersistenceWindowSizeValue.Text, 'full')
                        app.plotHandles.persistence = plot.Persistence('Update', app.plotHandles.persistence, app.UIAxes1, app.bandObj, app.sweepTimeIdx);
                        updatePersistencePanel(app, 'updateLimits')
                    end

                case 'Delete'
                    app.plotHandles.persistence = plot.Persistence('Delete', app.plotHandles.persistence);
                    updatePersistencePanel(app, 'initialization')
            end
        end

        %-----------------------------------------------------------------%
        % ## WATERFALL ##
        %-----------------------------------------------------------------%
        function updateWaterfallPanel(app)
            specData = app.bandObj.SpecData;
            elementHandles = findobj(app.WaterfallPanelGrid.Children, '-not', 'Type', 'uilabel', '-or', 'Type', 'uiimage');
            
            if app.axesTool_waterfall.UserData.status
                elementDisabled = [];
                if strcmp(app.WaterfallFunction.Value, 'image')
                    elementDisabled = app.WaterfallMeshStyle;
                end

                set(setdiff(elementHandles, elementDisabled), 'Enable', true)
                if ~isempty(elementDisabled)
                    set(elementDisabled, 'Enable', false)
                end

                app.restoreView(3).yLim = app.UIAxes3.YLim;
                app.restoreView(3).cLim = app.UIAxes3.CLim;

                app.WaterfallCLim1.Value = round(double(app.UIAxes3.CLim(1)));
                app.WaterfallCLim2.Value = round(double(app.UIAxes3.CLim(2)));

            else
                set(elementHandles, 'Enable', false)
            end

            updateUIControlsState(app, specData)
        end

        %-----------------------------------------------------------------%
        function updateWaterfallPlot(app)
            [app.plotHandles.waterfall, app.WaterfallDecimationValue.Text] = plot.Waterfall('Creation', app.plotHandles.waterfall, app.UIAxes3, app.bandObj);
            plot.axes.Layout.YLabel(app.plotHandles.waterfall, app.axesTool_waterfall.UserData.status)
            updateWaterfallPanel(app)

            % Timeline
            if app.mainApp.General.context.PLAYBACK.waterfallTimeVisibility
                app.plotHandles.waterfallTime = plot.draw2D.OrdinaryLine(app.UIAxes3, 'waterfallTime', app.bandObj, app.sweepTimeIdx);
            end
        end

        %-----------------------------------------------------------------%
        function updateOccupancyPlot(app, operationType, specData)
            switch operationType
                case 'Creation'
                    selectedHash = specData.UserData.OccupancyComputationMode.SelectedHash;
                    [~, selectedHashIdx] = ismember(selectedHash, {app.mainApp.specData.Hash});
        
                    if selectedHashIdx
                        occParameters  = struct('Method', 'Linear fixo (COLETA)', 'Threshold', app.mainApp.specData(selectedHashIdx).MetaData.Threshold);
                        occThreshold   = app.mainApp.specData(selectedHashIdx).MetaData.Threshold;
                        occAverageData = app.mainApp.specData(selectedHashIdx).Data{3}(2, :);
                    else
                        cacheIdx = specData.UserData.OccupancyComputationMode.CacheIndex;
                        if ~isempty(cacheIdx)
                            occParameters  = specData.UserData.OccupancyFiniteIntegrationCache(cacheIdx).Parameters;
                            occThreshold   = specData.UserData.OccupancyFiniteIntegrationCache(cacheIdx).Threshold;
                            occAverageData = specData.UserData.OccupancyFiniteIntegrationCache(cacheIdx).Data{3}(:, 2);
                        else
                            occParameters  = specData.UserData.OccupancyCumulativeIntegration.Parameters;
                            occThreshold   = specData.UserData.OccupancyCumulativeIntegration.Threshold;
                            occMatrix      = specData.UserData.OccupancyCumulativeIntegration.Matrix;
                            occAverageData = 100 * sum(occMatrix, 2) / width(occMatrix);
                        end
                    end
        
                    plot.Occupancy('Creation', app.UIAxes1, app.UIAxes2, occParameters, occThreshold, occAverageData, app.bandObj, app.mainApp.General)

                case 'Delete'
                    plot.Occupancy('Delete', app.UIAxes1, app.UIAxes2)
            end
        end

        %-----------------------------------------------------------------%
        % ## CHANNELS & EMISSIONS ##
        %-----------------------------------------------------------------%
        function updateChannelPlot(app, idx)
            delete(findobj(app.UIAxes1, 'Tag', 'Channel'))

            % if ~isempty(app.play_Channel_Tree.SelectedNodes) && app.play_Channel_ShowPlot.UserData
            %     chTable = table('Size',          [0, 6],                                                                      ...
            %                     'VariableNames', {'Name', 'FirstChannel', 'ChannelBW', 'Reference', 'FreqStart', 'FreqStop'}, ...
            %                     'VariableTypes', {'cell', 'double', 'double', 'cell', 'double', 'double'});
            % 
            %     for ii = 1:numel(app.play_Channel_Tree.SelectedNodes)
            %         srcChannel = app.play_Channel_Tree.SelectedNodes(ii).NodeData.src;
            %         idxChannel = app.play_Channel_Tree.SelectedNodes(ii).NodeData.idx;
            % 
            %         switch srcChannel
            %             case 'channelLib'
            %                 srcRawTable = app.channelObj.Channel(idxChannel);
            %             case 'manual'
            %                 srcRawTable = app.mainApp.specData(idx).UserData.ChannelUserDefined(idxChannel);
            %         end
            % 
            %         chTable = PreparingData2Plot(app.channelObj, chTable, srcRawTable);
            %     end
            % 
            %     if ~isempty(chTable)
            %         plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'Channel', chTable) 
            %     end
            % end
        end

        %-----------------------------------------------------------------%
        function updateEmissionPlot(app)
            % ...
        end

        %-----------------------------------------------------------------%
        % ## PLAYBACK ##
        %-----------------------------------------------------------------%
        function runPlaybackLoop(app, idx, nSweeps)
            app.tool_Play.ImageSource = 'playback-stop-16px-gray.png';                

            while app.sweepTimeIdx <= nSweeps
                switch app.plotUpdateEvent
                    case -1
                        app.plotUpdateEvent = 1;

                        idx = findSpecDataIndex(app);
                        updateFlowView(app, idx)

                        if isempty(idx)
                            break
                        end
                        
                        nSweeps = numel(app.mainApp.specData(idx).Data{1});

                    case  0
                        break
                end

                sweepTic = tic;
                
                updatePlot(app)
                app.tool_TimestampLabel.Text   = sprintf('%d de %d\n%s', app.sweepTimeIdx, nSweeps, app.mainApp.specData(idx).Data{1}(app.sweepTimeIdx));
                app.tool_TimestampSlider.Value = round(100 * app.sweepTimeIdx/nSweeps, 1);
                
                pause(max(app.mainApp.General.context.PLAYBACK.minSweepTimeSeconds - toc(sweepTic), .025)) % Valor mínimo: 25ms
                
                % Reload Flag
                if app.sweepTimeIdx == nSweeps
                    if ~app.tool_LoopControl.UserData.loopMode
                        break
                    end
                    app.sweepTimeIdx = 1;
                else
                    app.sweepTimeIdx = app.sweepTimeIdx+1;
                end                
            end

            app.plotUpdateEvent = 0;
            app.tool_Play.ImageSource = 'playback-play-16px-gray.png';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, filterTable, rfDataHubAnnotation)
            
            try
                appEngine.boot(app, app.Role, mainApp)                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if app.plotUpdateEvent
                app.plotUpdateEvent = 0;
                return
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            if app.plotUpdateEvent
                app.plotUpdateEvent = 0;
                return
            end

            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Value changed function: SpectrumFlowList
        function onFlowDropDownValueChanged(app, event)

            if app.plotUpdateEvent
                app.plotUpdateEvent = -1;
            else
                idx = findSpecDataIndex(app);
                updateFlowView(app, idx)
                updatePlot(app)
            end
            
        end

        % Image clicked function: FlowAttributesPanelLeftBtn, 
        % ...and 1 other component
        function onFlowPanelViewChanged(app, event)
            
            numPanels = 3;

            panelSubtitles = {'Metadados', 'Emissões', 'Canais'};
            panelBtnStatus = [false true; true true; true false];
            columnWidths = {
                {'1x',0,0,0,0,0,0,0};
                {0,10,'1x',18,10,0,0,0};
                {0,0,0,0,10,'1x',18,10}
            };

            currentIndex = app.FlowAttributesPanelVisibleIdx.UserData.index;
            
            switch event.Source
                case app.FlowAttributesPanelLeftBtn
                    step = -1;
                case app.FlowAttributesPanelRightBtn
                    step = 1;
            end

            currentIndex = mod(currentIndex - 1 + step, numPanels) + 1;
            
            app.FlowAttributesPanelLeftBtn.Enable  = panelBtnStatus(currentIndex, 1);
            app.FlowAttributesPanelRightBtn.Enable = panelBtnStatus(currentIndex, 2);
            app.FlowAttributesPanelVisibleIdx.Text = sprintf('%d/%d', currentIndex, numPanels);

            app.FlowPanelGrid.ColumnWidth = columnWidths{currentIndex};
            app.FlowEmissions.Visible = ismember(panelSubtitles{currentIndex}, {'Metadados', 'Emissões'});

            app.FlowPanelLabel.Text = replace(app.FlowPanelLabel.Text, extractBetween(app.FlowPanelLabel.Text, '<i>', '</i>'), panelSubtitles{currentIndex});
            app.FlowAttributesPanelVisibleIdx.UserData.index = currentIndex;

        end

        % Image clicked function: axesTool_DataTip, axesTool_Pan, 
        % ...and 8 other components
        function onAxesToolbarButtonClicked(app, event)
            
            specData = app.bandObj.SpecData;

            switch event.Source
                case app.axesTool_RestoreView
                    plot.axes.Interactivity.CustomRestoreViewFcn(app.UIAxes1, [app.UIAxes2, app.UIAxes3], app)

                case app.axesTool_Pan
                    app.axesTool_Pan.UserData.status = ~app.axesTool_Pan.UserData.status;
                    if app.axesTool_Pan.UserData.status
                        app.axesTool_Pan.ImageSource = 'pan-filled-32px.png';
                        
                        if app.axesTool_DataTip.UserData.status
                            onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_DataTip))
                        end
                    else
                        app.axesTool_Pan.ImageSource = 'pan-32px.png';
                    end

                    plot.axes.Interactivity.CustomPanFcn(struct('Value', app.axesTool_Pan.UserData.status), [app.UIAxes1, app.UIAxes2, app.UIAxes3]);

                case app.axesTool_crearWrite
                    if ~isempty(app.plotHandles.clearWrite) && isvalid(app.plotHandles.clearWrite)
                        app.plotHandles.clearWrite.Visible = ~app.plotHandles.clearWrite.Visible;
                    end

                case {app.axesTool_minHold, app.axesTool_average, app.axesTool_maxHold}
                    event.Source.UserData.status = ~event.Source.UserData.status;

                    plotTag = event.Source.Tag;
                    update(specData, 'UserData:PlotDisplayConfig', plotTag, event.Source.UserData.status)

                    if event.Source.UserData.status
                        event.Source.ImageSource = event.Source.UserData.imageSource{1};

                        hObject = plot.draw2D.OrdinaryLine(app.UIAxes1, plotTag, app.bandObj, app.sweepTimeIdx);
                        plot.datatip.Template(hObject, 'Frequency+Level', app.bandObj.LevelUnit)
                        plot.axes.StackingOrder.execute(app.UIAxes1, app.bandObj.Context)
            
                        app.plotHandles.(plotTag) = hObject;

                    else
                        event.Source.ImageSource = event.Source.UserData.imageSource{2};
                        delete(app.plotHandles.(plotTag))
                        app.plotHandles.(plotTag) = [];
                    end

                case app.axesTool_persistence
                    app.axesTool_persistence.UserData.status = ~app.axesTool_persistence.UserData.status;

                    update(specData, 'UserData:PlotDisplayConfig', 'persistence', event.Source.UserData.status)

                    if app.axesTool_persistence.UserData.status
                        updatePersistencePlot(app, 'Creation')
                    else
                        updatePersistencePlot(app, 'Delete')
                    end

                case app.axesTool_occupancy
                    app.axesTool_occupancy.UserData.status = ~app.axesTool_occupancy.UserData.status;

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)

                    update(specData, 'UserData:PlotDisplayConfig', 'occupancy', event.Source.UserData.status)

                    if app.axesTool_occupancy.UserData.status
                        updateOccupancyPlot(app, 'Creation', specData)
                    else
                        updateOccupancyPlot(app, 'Delete', specData)
                    end

                case app.axesTool_waterfall
                    app.axesTool_waterfall.UserData.status = ~app.axesTool_waterfall.UserData.status;

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)

                    update(specData, 'UserData:PlotDisplayConfig', 'waterfall', event.Source.UserData.status)

                    if app.axesTool_waterfall.UserData.status
                        if isempty(app.plotHandles.waterfall)
                            updateWaterfallPlot(app)
                        else
                            updateWaterfallPanel(app)
                        end
                    else
                        updateWaterfallPanel(app)
                    end

                case app.axesTool_DataTip
                    app.axesTool_DataTip.UserData.status = ~app.axesTool_DataTip.UserData.status;
                    if app.axesTool_DataTip.UserData.status
                        app.axesTool_DataTip.ImageSource = 'datatip-filled-20px.png';
                    else
                        app.axesTool_DataTip.ImageSource = 'datatip-20px.png';
                    end

                    plot.axes.Interactivity.DataCursorMode(app.UIAxes3, app.axesTool_DataTip.UserData.status)
            end
            drawnow

        end

        % Value changed function: LayoutRatio, PersistenceColormap, 
        % ...and 7 other components
        function onDisplayPlotConfigChanged(app, event)
            
            specData = app.bandObj.SpecData;
            update(specData, 'UserData:PlotDisplayConfig', event.Source.Tag, event.Value)

            switch event.Source
                case app.LayoutRatio  
                    plot.axes.Layout.Visibility([app.UIAxes1, app.UIAxes2, app.UIAxes3], event.Value, app.bandObj.Context)

                case app.PersistenceInterpolation
                    app.plotHandles.persistence.handle.Interpolation = event.Value;

                case app.PersistenceWindowSize
                    updatePersistencePlot(app, 'Delete')
                    updatePersistencePlot(app, 'Creation')

                case app.PersistenceColormap
                    plot.axes.Colormap(app.UIAxes1, event.Value)

                case app.PersistenceTransparency
                    app.plotHandles.persistence.handle.CData(isnan(app.plotHandles.persistence.handle.CData)) = 0; 
                    app.plotHandles.persistence.handle.AlphaData = double(logical(app.plotHandles.persistence.handle.CData)) * event.Value;

                case { app.WaterfallFunction, app.WaterfallDecimation }
                    [~, ~, XData, YData] = plot.datatip.Search(app.UIAxes3);
                    app.plotHandles.waterfall = plot.Waterfall('Delete', app.plotHandles.waterfall);
                    updateWaterfallPlot(app)

                    if ~isempty(XData)
                        if event.Source == app.WaterfallFunction
                            for ii = 1:numel(YData)
                                switch event.Value
                                    case 'image'
                                        YData{ii} = timestamp2idx(app.bandObj, YData{ii});
                                    case 'mesh'
                                        YData{ii} = idx2timestamp(app.bandObj, YData{ii});
                                end
                            end
                        end

                        dtConfig = struct('XData', XData, 'YData', YData);
                        dtParent = app.plotHandles.waterfall;
                        plot.datatip.Create('redrawWaterfall', dtConfig, dtParent)                        
                    end                    

                case app.WaterfallColormap
                    plot.axes.Colormap(app.UIAxes3, event.Value)

                case app.WaterfallMeshStyle
                    app.plotHandles.waterfall.MeshStyle = event.Value;
            end
            
        end

        % Callback function: LimitsRefresh, LimitsXLim1, LimitsXLim2, 
        % ...and 8 other components
        function onAxesLimitsConfigChanged(app, event)
            
            specData = app.bandObj.SpecData;
            evtValue = [];

            switch event.Source
                case app.LimitsRefresh
                    if ~isempty(specData)
                        xLimits = specData.UserData.PlotDisplayConfig.limits.frequency.initial;
                        yLimits = specData.UserData.PlotDisplayConfig.limits.level.initial;
                    else
                        xLimits = [0, 1];
                        yLimits = [0, 1];
                    end
                    set(app.UIAxes1, 'XLim', xLimits, 'YLim', yLimits)

                case { app.LimitsXLim1, app.LimitsXLim2 }
                    evtValue = [app.LimitsXLim1.Value, app.LimitsXLim2.Value];                    
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes1.XLim = evtValue;
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case { app.LimitsYLim1, app.LimitsYLim2 }
                    evtValue = [app.LimitsYLim1.Value, app.LimitsYLim2.Value];
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes1.YLim = evtValue;
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case { app.PersistenceCLim1, app.PersistenceCLim2 }
                    evtValue = [app.PersistenceCLim1.Value, app.PersistenceCLim2.Value];
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes1.CLim = evtValue;
                        app.UIAxes1.UserData.CLimMode = 'manual';
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case app.PersistenceCLimRefresh
                    app.UIAxes1.CLimMode = 'auto';
                    app.UIAxes1.UserData.CLimMode = 'auto';

                    app.PersistenceCLim1.Value = round(app.UIAxes1.CLim(1), 3);
                    app.PersistenceCLim2.Value = round(app.UIAxes1.CLim(2), 3);

                case { app.WaterfallCLim1, app.WaterfallCLim2 }
                    evtValue = [app.WaterfallCLim1.Value, app.WaterfallCLim2.Value];
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes3.CLim = evtValue;
                        app.UIAxes3.UserData.CLimMode = 'manual';
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case app.WaterfallCLimRefresh
                    app.UIAxes3.CLim = specData.UserData.PlotDisplayConfig.limits.waterfall.initial;
                    app.UIAxes3.UserData.CLimMode = 'auto';

                    app.WaterfallCLim1.Value = round(app.UIAxes3.CLim(1));
                    app.WaterfallCLim2.Value = round(app.UIAxes3.CLim(2));
            end

            if ~isempty(specData)
                update(specData, 'UserData:PlotDisplayConfig', event.Source.Tag, evtValue)
                updateSpectrumInfo(app.bandObj, specData);
                resetRestoreView(app)
            end
            checkAxesLimitsCustomizations(app)
            
        end

        % Image clicked function: tool_LayoutLeft, tool_LayoutRight
        function tool_PanelVisibilityButtonPushed(app, event)
            
            switch event.Source
                case app.tool_LayoutLeft
                    if app.Document.ColumnWidth{1}
                        app.tool_LayoutLeft.ImageSource     = 'layout-sidebar-left-off.svg';
                        app.Document.ColumnWidth(1:2)       = {0,0};
                    else
                        app.tool_LayoutLeft.ImageSource     = 'layout-sidebar-left.svg';
                        app.Document.ColumnWidth(1:2)       = {320,10};
                    end

                case app.tool_LayoutRight
                    if app.Document.ColumnWidth{end}
                        app.tool_LayoutRight.ImageSource    = 'layout-sidebar-right-off.svg';
                        app.Document.ColumnWidth(end-1:end) = {0,0};
                    else
                        app.tool_LayoutRight.ImageSource    = 'layout-sidebar-right.svg';
                        app.Document.ColumnWidth(end-1:end) = {10,232};
                    end
            end

        end

        % Callback function: tool_TimestampSlider, tool_TimestampSlider
        function tool_TimestampSliderValueChanged(app, event)
            
            nSweeps = app.bandObj.NumSweeps;            
            app.sweepTimeIdx = round(event.Value/100 * nSweeps);
            
            if app.sweepTimeIdx < 1
                app.sweepTimeIdx = 1;
            elseif app.sweepTimeIdx > nSweeps
                app.sweepTimeIdx = nSweeps;
            end

            if ~app.plotUpdateEvent
                % idx = app.play_PlotPanel.UserData.NodeData;
                app.plotHandles.('clearWrite').YData = app.bandObj.SpecData.Data{2}(:, app.sweepTimeIdx)';
    
                % for ii = 1:numel(app.hEmissionMarkers)
                %     app.hEmissionMarkers(ii).Position(2) = app.hClearWrite.YData(app.hClearWrite.MarkerIndices(ii));
                % end
                
                updatePersistencePlot(app, 'Update')

                if app.axesTool_waterfall.UserData.status && ~isempty(app.plotHandles.waterfallTime)
                    plot.draw2D.OrdinaryLineUpdate('waterfallTime', app.plotHandles.waterfallTime, app.bandObj, app.sweepTimeIdx);
                end

                app.tool_TimestampLabel.Text = sprintf('%d de %d\n%s', app.sweepTimeIdx, nSweeps, app.bandObj.SpecData.Data{1}(app.sweepTimeIdx));
            end
            
        end

        % Image clicked function: tool_LoopControl, tool_Play
        function tool_PlaybackControlButtonPushed(app, event)
            
            switch event.Source
                case app.tool_Play
                    idx = findSpecDataIndex(app);

                    if ~isempty(idx) && ~app.plotUpdateEvent
                        ipcMainMatlabCallsHandler(app.mainApp, app, 'onPlaybackStarted')

                        app.plotUpdateEvent = 1;
                        runPlaybackLoop(app, idx, numel(app.mainApp.specData(idx).Data{1}))        
                    else
                        app.plotUpdateEvent = 0;
                    end

                %---------------------------------------------------------%
                case app.tool_LoopControl
                     app.tool_LoopControl.UserData.loopMode = ~ app.tool_LoopControl.UserData.loopMode;

                     if app.tool_LoopControl.UserData.loopMode
                         app.tool_LoopControl.ImageSource = 'playback-loop-36px-gray.png';
                     else
                         app.tool_LoopControl.ImageSource = 'playback-straight-36px-gray.png';
                    end
            end

        end

        % Image clicked function: FlowChannelEdit, 
        % ...and 2 other components
        function openPopupApp(app, event)
            
            switch event.Source
                case app.FlowDetectionLimitsEdit
                    ipcMainMatlabOpenPopupApp(app.mainApp, app, 'DetectionLimits', app.Context)
                case app.FlowEmissionsAdd
                    ipcMainMatlabOpenPopupApp(app.mainApp, app, 'Detection', app.Context)
                case app.FlowChannelEdit
                    ipcMainMatlabOpenPopupApp(app.mainApp, app, 'Channels', app.Context)
            end

        end

        % Image clicked function: axesTool_FlowInfo
        function axesTool_FlowInfoImageClicked(app, event)
            
            specData = app.bandObj.SpecData;
            
            if ~isempty(specData)
                flowAnalysis = util.HtmlTextGenerator.ThreadAnalysis(specData);
                ui.Dialog(app.UIFigure, 'info', flowAnalysis);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_LayoutLeft = uiimage(app.Toolbar);
            app.tool_LayoutLeft.ImageClickedFcn = createCallbackFcn(app, @tool_PanelVisibilityButtonPushed, true);
            app.tool_LayoutLeft.ImageSource = 'layout-sidebar-left.svg';
            app.tool_Play = uiimage(app.Toolbar);
            app.tool_Play.ImageClickedFcn = createCallbackFcn(app, @tool_PlaybackControlButtonPushed, true);
            app.tool_Play.Enable = 'off';
            app.tool_Play.ImageSource = 'playback-play-16px-gray.png';
            app.tool_LoopControl = uiimage(app.Toolbar);
            app.tool_LoopControl.ImageClickedFcn = createCallbackFcn(app, @tool_PlaybackControlButtonPushed, true);
            app.tool_LoopControl.Enable = 'off';
            app.tool_LoopControl.ImageSource = 'playback-loop-36px-gray.png';
            app.tool_TimestampSlider = uislider(app.Toolbar);
            app.tool_TimestampSlider.MajorTicks = [0 50 100];
            app.tool_TimestampSlider.ValueChangedFcn = createCallbackFcn(app, @tool_TimestampSliderValueChanged, true);
            app.tool_TimestampSlider.ValueChangingFcn = createCallbackFcn(app, @tool_TimestampSliderValueChanged, true);
            app.tool_TimestampSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.tool_TimestampSlider.Enable = 'off';
            app.tool_TimestampLabel = uilabel(app.Toolbar);
            app.tool_TimestampLabel.Text = {'22 de 328 '; '22/02/2022 08:00:00 '};
            app.tool_GenerateReport = uiimage(app.Toolbar);
            app.tool_GenerateReport.ImageSource = 'Publish_HTML_16.png';
            app.tool_UploadFinalFile = uiimage(app.Toolbar);
            app.tool_UploadFinalFile.ImageSource = 'up-20px.png';
            app.tool_LayoutRight = uiimage(app.Toolbar);
            app.tool_LayoutRight.ImageClickedFcn = createCallbackFcn(app, @tool_PanelVisibilityButtonPushed, true);
            app.tool_LayoutRight.ImageSource = 'layout-sidebar-right.svg';
            app.tool_OpenPopupProject = uiimage(app.Toolbar);
            app.tool_OpenPopupProject.ImageSource = 'organization-20px-black.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.AxesContainer = uipanel(app.Document);
            app.AxesToolbar = uigridlayout(app.Document);
            app.axesTool_RestoreView = uiimage(app.AxesToolbar);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_Pan = uiimage(app.AxesToolbar);
            app.axesTool_Pan.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_Pan.ImageSource = 'pan-32px.png';
            app.axesTool_Separator1 = uiimage(app.AxesToolbar);
            app.axesTool_Separator1.Enable = 'off';
            app.axesTool_Separator1.ImageSource = 'LineV.svg';
            app.axesTool_crearWrite = uiimage(app.AxesToolbar);
            app.axesTool_crearWrite.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_crearWrite.Tag = 'minHold';
            app.axesTool_crearWrite.Enable = 'off';
            app.axesTool_crearWrite.ImageSource = 'eye-closed-16px.svg';
            app.axesTool_minHold = uiimage(app.AxesToolbar);
            app.axesTool_minHold.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_minHold.Tag = 'minHold';
            app.axesTool_minHold.Enable = 'off';
            app.axesTool_minHold.ImageSource = 'MinHold_32.png';
            app.axesTool_average = uiimage(app.AxesToolbar);
            app.axesTool_average.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_average.Tag = 'average';
            app.axesTool_average.Enable = 'off';
            app.axesTool_average.ImageSource = 'Average_32.png';
            app.axesTool_maxHold = uiimage(app.AxesToolbar);
            app.axesTool_maxHold.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_maxHold.Tag = 'maxHold';
            app.axesTool_maxHold.Enable = 'off';
            app.axesTool_maxHold.ImageSource = 'MaxHold_32.png';
            app.axesTool_persistence = uiimage(app.AxesToolbar);
            app.axesTool_persistence.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_persistence.Tag = 'persistence';
            app.axesTool_persistence.Enable = 'off';
            app.axesTool_persistence.ImageSource = 'persistence-36px.png';
            app.axesTool_Separator2 = uiimage(app.AxesToolbar);
            app.axesTool_Separator2.Enable = 'off';
            app.axesTool_Separator2.ImageSource = 'LineV.svg';
            app.axesTool_occupancy = uiimage(app.AxesToolbar);
            app.axesTool_occupancy.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_occupancy.Tag = 'occupancy';
            app.axesTool_occupancy.Enable = 'off';
            app.axesTool_occupancy.ImageSource = 'Occupancy_32.png';
            app.axesTool_Separator3 = uiimage(app.AxesToolbar);
            app.axesTool_Separator3.Enable = 'off';
            app.axesTool_Separator3.ImageSource = 'LineV.svg';
            app.axesTool_waterfall = uiimage(app.AxesToolbar);
            app.axesTool_waterfall.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_waterfall.Tag = 'waterfall';
            app.axesTool_waterfall.Enable = 'off';
            app.axesTool_waterfall.ImageSource = 'waterfall-22px.png';
            app.axesTool_DataTip = uiimage(app.AxesToolbar);
            app.axesTool_DataTip.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_DataTip.Enable = 'off';
            app.axesTool_DataTip.ImageSource = 'datatip-20px.png';
            app.axesTool_Separator3_2 = uiimage(app.AxesToolbar);
            app.axesTool_Separator3_2.Enable = 'off';
            app.axesTool_Separator3_2.ImageSource = 'LineV.svg';
            app.axesTool_FlowInfo = uiimage(app.AxesToolbar);
            app.axesTool_FlowInfo.ImageClickedFcn = createCallbackFcn(app, @axesTool_FlowInfoImageClicked, true);
            app.axesTool_FlowInfo.Enable = 'off';
            app.axesTool_FlowInfo.ImageSource = 'Info_32.png';
            app.AxesAnnotation = uilabel(app.Document);
            app.AxesAnnotation.Text = '';
            app.LeftPanel = uigridlayout(app.Document);
            app.SpectrumFlowList = uidropdown(app.LeftPanel);
            app.SpectrumFlowList.Items = {};
            app.SpectrumFlowList.ValueChangedFcn = createCallbackFcn(app, @onFlowDropDownValueChanged, true);
            app.SpectrumFlowList.Value = {};
            app.FlowPanelLabel = uilabel(app.LeftPanel);
            app.FlowPanelLabel.Interpreter = 'html';
            app.FlowPanelLabel.Text = 'ATRIBUTOS DO FLUXO ESPECTRAL<br><font style="font-size: 11px;"><i>Metadados</i></font>';
            app.FlowAttributesPanelVisibleIdx = uilabel(app.LeftPanel);
            app.FlowAttributesPanelVisibleIdx.Text = '1/3';
            app.FlowAttributesPanelLeftBtn = uiimage(app.LeftPanel);
            app.FlowAttributesPanelLeftBtn.ImageClickedFcn = createCallbackFcn(app, @onFlowPanelViewChanged, true);
            app.FlowAttributesPanelLeftBtn.Enable = 'off';
            app.FlowAttributesPanelLeftBtn.ImageSource = 'triangle-left.svg';
            app.FlowAttributesPanelRightBtn = uiimage(app.LeftPanel);
            app.FlowAttributesPanelRightBtn.ImageClickedFcn = createCallbackFcn(app, @onFlowPanelViewChanged, true);
            app.FlowAttributesPanelRightBtn.ImageSource = 'triangle-right.svg';
            app.FlowPanel = uipanel(app.LeftPanel);
            app.FlowPanelGrid = uigridlayout(app.FlowPanel);
            app.FlowMetadata = uilabel(app.FlowPanelGrid);
            app.FlowMetadata.Interpreter = 'html';
            app.FlowMetadata.Text = '';
            app.FlowDetectionLabel = uilabel(app.FlowPanelGrid);
            app.FlowDetectionLabel.Text = 'LIMITES DE DETECÇÃO';
            app.FlowDetectionLimitsEdit = uiimage(app.FlowPanelGrid);
            app.FlowDetectionLimitsEdit.ImageClickedFcn = createCallbackFcn(app, @openPopupApp, true);
            app.FlowDetectionLimitsEdit.Enable = 'off';
            app.FlowDetectionLimitsEdit.ImageSource = 'Edit_32.png';
            app.FlowDetectionLimits = uilistbox(app.FlowPanelGrid);
            app.FlowDetectionLimits.Items = {};
            app.FlowDetectionLimits.Value = {};
            app.FlowEmissionsLabel = uilabel(app.FlowPanelGrid);
            app.FlowEmissionsLabel.Text = 'EMISSÕES';
            app.FlowEmissionsAdd = uiimage(app.FlowPanelGrid);
            app.FlowEmissionsAdd.ImageClickedFcn = createCallbackFcn(app, @openPopupApp, true);
            app.FlowEmissionsAdd.Enable = 'off';
            app.FlowEmissionsAdd.ImageSource = 'search-sparkle.svg';
            app.FlowEmissions = uitable(app.FlowPanelGrid);
            app.FlowEmissions.ColumnName = {'#'; 'FREQUÊNCIA|(MHz)'; 'LARGURA|(kHz)'; 'INFORMAÇÕES|ADICIONAIS'};
            app.FlowEmissions.RowName = {};
            app.FlowEmissions.SelectionType = 'row';
            app.FlowEmissions.ColumnEditable = [false true true true];
            app.FlowChannelLabel = uilabel(app.FlowPanelGrid);
            app.FlowChannelLabel.Text = 'CANAIS';
            app.FlowChannelEdit = uiimage(app.FlowPanelGrid);
            app.FlowChannelEdit.ImageClickedFcn = createCallbackFcn(app, @openPopupApp, true);
            app.FlowChannelEdit.Enable = 'off';
            app.FlowChannelEdit.ImageSource = 'Edit_32.png';
            app.FlowChannel = uilistbox(app.FlowPanelGrid);
            app.FlowChannel.Items = {};
            app.FlowChannel.Value = {};
            app.RightPanel = uigridlayout(app.Document);
            app.GeneralPanelIcon = uiimage(app.RightPanel);
            app.GeneralPanelIcon.ImageSource = 'DriveTestDensity_32.png';
            app.GeneralPanelLabel = uilabel(app.RightPanel);
            app.GeneralPanelLabel.Text = 'EIXOS GRÁFICOS';
            app.GeneralPanel = uipanel(app.RightPanel);
            app.GeneralPanelGrid = uigridlayout(app.GeneralPanel);
            app.LayoutRatioLabel = uilabel(app.GeneralPanelGrid);
            app.LayoutRatioLabel.Text = 'Razão de aspecto:';
            app.LayoutRatio = uidropdown(app.GeneralPanelGrid);
            app.LayoutRatio.Items = {'1:0:0'};
            app.LayoutRatio.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.LayoutRatio.Tag = 'layoutRatio';
            app.LayoutRatio.Value = '1:0:0';
            app.LimitsPanelLabel = uilabel(app.GeneralPanelGrid);
            app.LimitsPanelLabel.Text = 'Limites de frequência e nível:';
            app.LimitsRefresh = uiimage(app.GeneralPanelGrid);
            app.LimitsRefresh.ImageClickedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsRefresh.Tag = 'limitsXYRefresh';
            app.LimitsRefresh.Visible = 'off';
            app.LimitsRefresh.ImageSource = 'Refresh_18.png';
            app.LimitsXLimLabel = uilabel(app.GeneralPanelGrid);
            app.LimitsXLimLabel.Text = 'MHz  ';
            app.LimitsXLim1 = uispinner(app.GeneralPanelGrid);
            app.LimitsXLim1.ValueDisplayFormat = '%.3f';
            app.LimitsXLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsXLim1.Tag = 'limitsX';
            app.LimitsXLim2 = uispinner(app.GeneralPanelGrid);
            app.LimitsXLim2.ValueDisplayFormat = '%.3f';
            app.LimitsXLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsXLim2.Tag = 'limitsX';
            app.LimitsXLim2.Value = 1;
            app.LimitsYLimLabel = uilabel(app.GeneralPanelGrid);
            app.LimitsYLimLabel.Text = 'dB  ';
            app.LimitsYLim1 = uispinner(app.GeneralPanelGrid);
            app.LimitsYLim1.Step = 5;
            app.LimitsYLim1.ValueDisplayFormat = '%.1f';
            app.LimitsYLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsYLim1.Tag = 'limitsY';
            app.LimitsYLim2 = uispinner(app.GeneralPanelGrid);
            app.LimitsYLim2.Step = 5;
            app.LimitsYLim2.ValueDisplayFormat = '%.1f';
            app.LimitsYLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsYLim2.Tag = 'limitsY';
            app.LimitsYLim2.Value = 1;
            app.PersistencePanelIcon = uiimage(app.RightPanel);
            app.PersistencePanelIcon.ImageSource = 'persistence-36px.png';
            app.PersistencePanelLabel = uilabel(app.RightPanel);
            app.PersistencePanelLabel.Text = 'PERSISTÊNCIA';
            app.PersistencePanel = uipanel(app.RightPanel);
            app.PersistencePanelGrid = uigridlayout(app.PersistencePanel);
            app.PersistenceInterpolationLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceInterpolationLabel.Text = 'Interpolação:';
            app.PersistenceInterpolation = uidropdown(app.PersistencePanelGrid);
            app.PersistenceInterpolation.Items = {'nearest', 'bilinear'};
            app.PersistenceInterpolation.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceInterpolation.Tag = 'persistenceInterpolation';
            app.PersistenceInterpolation.Enable = 'off';
            app.PersistenceInterpolation.Value = 'nearest';
            app.PersistenceWindowSizeLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceWindowSizeLabel.Text = 'Tamanho janela:';
            app.PersistenceWindowSizeValue = uilabel(app.PersistencePanelGrid);
            app.PersistenceWindowSizeValue.Text = 'full';
            app.PersistenceWindowSize = uidropdown(app.PersistencePanelGrid);
            app.PersistenceWindowSize.Items = {'128', '256', '512', 'full'};
            app.PersistenceWindowSize.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceWindowSize.Tag = 'persistenceWindowSize';
            app.PersistenceWindowSize.Enable = 'off';
            app.PersistenceWindowSize.Value = 'full';
            app.PersistenceColormapLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceColormapLabel.Text = 'Mapa de cores:';
            app.PersistenceColormap = uidropdown(app.PersistencePanelGrid);
            app.PersistenceColormap.Items = {'winter', 'parula', 'turbo'};
            app.PersistenceColormap.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceColormap.Tag = 'persistenceColormap';
            app.PersistenceColormap.Enable = 'off';
            app.PersistenceColormap.Value = 'winter';
            app.PersistenceTransparencyLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceTransparencyLabel.Text = 'Transparência:';
            app.PersistenceTransparency = uispinner(app.PersistencePanelGrid);
            app.PersistenceTransparency.Step = 0.05;
            app.PersistenceTransparency.Limits = [0.2 1];
            app.PersistenceTransparency.ValueDisplayFormat = '%.2f';
            app.PersistenceTransparency.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceTransparency.Tag = 'persistenceTransparency';
            app.PersistenceTransparency.Enable = 'off';
            app.PersistenceTransparency.Value = 1;
            app.PersistenceCLim_Label = uilabel(app.PersistencePanelGrid);
            app.PersistenceCLim_Label.Text = 'Limites de intensidade (%):';
            app.PersistenceCLimRefresh = uiimage(app.PersistencePanelGrid);
            app.PersistenceCLimRefresh.ImageClickedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.PersistenceCLimRefresh.Tag = 'limitsPersistenceRefresh';
            app.PersistenceCLimRefresh.Visible = 'off';
            app.PersistenceCLimRefresh.ImageSource = 'Refresh_18.png';
            app.PersistenceCLim1 = uispinner(app.PersistencePanelGrid);
            app.PersistenceCLim1.Step = 0.1;
            app.PersistenceCLim1.Limits = [0 Inf];
            app.PersistenceCLim1.ValueDisplayFormat = '%.3f';
            app.PersistenceCLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.PersistenceCLim1.Tag = 'limitsPersistence';
            app.PersistenceCLim1.Enable = 'off';
            app.PersistenceCLim1.Value = 0.1;
            app.PersistenceCLim2 = uispinner(app.PersistencePanelGrid);
            app.PersistenceCLim2.Limits = [0 Inf];
            app.PersistenceCLim2.ValueDisplayFormat = '%.3f';
            app.PersistenceCLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.PersistenceCLim2.Tag = 'limitsPersistence';
            app.PersistenceCLim2.Enable = 'off';
            app.PersistenceCLim2.Value = 1;
            app.WaterfallPanelIcon = uiimage(app.RightPanel);
            app.WaterfallPanelIcon.ImageSource = 'waterfall-22px.png';
            app.WaterfallPanelLabel = uilabel(app.RightPanel);
            app.WaterfallPanelLabel.Text = 'WATERFALL';
            app.WaterfallPanel = uipanel(app.RightPanel);
            app.WaterfallPanelGrid = uigridlayout(app.WaterfallPanel);
            app.WaterfallFunctionLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallFunctionLabel.Text = 'Renderização:';
            app.WaterfallFunction = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallFunction.Items = {'image', 'mesh'};
            app.WaterfallFunction.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallFunction.Tag = 'waterfallFunction';
            app.WaterfallFunction.Enable = 'off';
            app.WaterfallFunction.Value = 'image';
            app.WaterfallDecimationLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallDecimationLabel.Text = 'Decimação:';
            app.WaterfallDecimationValue = uilabel(app.WaterfallPanelGrid);
            app.WaterfallDecimationValue.Text = 'auto';
            app.WaterfallDecimation = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallDecimation.Items = {'auto', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.WaterfallDecimation.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallDecimation.Tag = 'waterfallDecimation';
            app.WaterfallDecimation.Enable = 'off';
            app.WaterfallDecimation.Value = 'auto';
            app.WaterfallColormapLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallColormapLabel.Text = 'Mapa de cores:';
            app.WaterfallColormap = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallColormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.WaterfallColormap.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallColormap.Tag = 'waterfallColormap';
            app.WaterfallColormap.Enable = 'off';
            app.WaterfallColormap.Value = 'winter';
            app.WaterfallMeshStyleLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallMeshStyleLabel.Text = 'Linhas da superfície:';
            app.WaterfallMeshStyle = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallMeshStyle.Items = {'row', 'both'};
            app.WaterfallMeshStyle.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallMeshStyle.Tag = 'waterfallMeshStyle';
            app.WaterfallMeshStyle.Enable = 'off';
            app.WaterfallMeshStyle.Value = 'row';
            app.WaterfallCLimLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallCLimLabel.Text = 'Limites de nível (dB):';
            app.WaterfallCLimRefresh = uiimage(app.WaterfallPanelGrid);
            app.WaterfallCLimRefresh.ImageClickedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.WaterfallCLimRefresh.Tag = 'limitsWaterfallRefresh';
            app.WaterfallCLimRefresh.Visible = 'off';
            app.WaterfallCLimRefresh.ImageSource = 'Refresh_18.png';
            app.WaterfallCLim1 = uispinner(app.WaterfallPanelGrid);
            app.WaterfallCLim1.Step = 5;
            app.WaterfallCLim1.RoundFractionalValues = 'on';
            app.WaterfallCLim1.ValueDisplayFormat = '%.0f';
            app.WaterfallCLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.WaterfallCLim1.Tag = 'limitsWaterfall';
            app.WaterfallCLim1.Enable = 'off';
            app.WaterfallCLim2 = uispinner(app.WaterfallPanelGrid);
            app.WaterfallCLim2.Step = 5;
            app.WaterfallCLim2.RoundFractionalValues = 'on';
            app.WaterfallCLim2.ValueDisplayFormat = '%.0f';
            app.WaterfallCLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.WaterfallCLim2.Tag = 'limitsWaterfall';
            app.WaterfallCLim2.Enable = 'off';
            app.WaterfallCLim2.Value = 1;
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winPlayback(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end